classdef SYNTH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        OscillatorFrequencyEditField  matlab.ui.control.EditField
        EnterFrequencyForOscillatorEditFieldLabel  matlab.ui.control.Label
        SawtoothAmpSlider             matlab.ui.control.Slider
        AmplitudeSlider_3Label        matlab.ui.control.Label
        TuneSlider                    matlab.ui.control.Slider
        TuneSliderLabel               matlab.ui.control.Label
        SquareOscWidthSlider          matlab.ui.control.Slider
        WidthSliderLabel              matlab.ui.control.Label
        SquareOscAmpSlider            matlab.ui.control.Slider
        AmplitudeSliderLabel          matlab.ui.control.Label
        EnvelopeAmountSlider          matlab.ui.control.Slider
        EnvelopeAmountSliderLabel     matlab.ui.control.Label
        LFOAmountSlider               matlab.ui.control.Slider
        LFOAmountSliderLabel          matlab.ui.control.Label
        SawtoothOscAxes               matlab.ui.control.UIAxes
        SquareOscAxes                 matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        VolumeSlider                  matlab.ui.control.Slider
        VolumeSliderLabel             matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        ImportFileButton              matlab.ui.control.Button
        MediaControlLabel             matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        TimeAxes                      matlab.ui.control.UIAxes
        FreqAxes                      matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout1                   matlab.ui.container.GridLayout
        AttackSlider                  matlab.ui.control.Slider
        DecaySlider                   matlab.ui.control.Slider
        DecaysLabel                   matlab.ui.control.Label
        AttacksLabel                  matlab.ui.control.Label
        SustainSlider                 matlab.ui.control.Slider
        ReleasesSlider                matlab.ui.control.Slider
        ReleasesSliderLabel           matlab.ui.control.Label
        SustainSliderLabel            matlab.ui.control.Label
        ADSREnvelopeAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % instance vars:
    % originalWaveFileObj - object holding all information in original wav
    % currWaveData - object holding all information in new wave file
    % squareOscillator - time domain array storing square oscillator
    % sawtoothOscillator - time domain array storing sawtooth oscillator
    % 
    % squareWidth - parameter to control duty cycle of square oscillator
    % squareAmplitude - amplitude of square oscillator
    %
    % sawtoothAmplitude - amplitude of sawtooth oscillator

    properties (Access = private)

        originalWaveFileObj
        currWaveFileObj
        squareOscillator
        sawtoothOscillator

        duration
        fs
        waveAmplitude

        oscillatorFrequency
        squareAmplitude
        squareWidth
        sawtoothAmplitude

        adsrEnvelope
        adsrAttack
        adsrDecay
        adsrSustain
        adsrRelease

    end
    
    % All helper methods to handle gui interaction
    methods (Access = private)

        %{
            Initializes the .wav file object to be used

            @param filePath - the path of the file read in
        %}
        function loadWav(app, filePath)
        
            app.originalWaveFileObj = WaveFile(filePath);
            app.currWaveFileObj = app.originalWaveFileObj;
        end
        
        %{
            Initializes the square oscillator
        %}
        function initializeSquare(app, amplitude, freq, width, duration, fs)

            app.squareOscillator = generateSquareOscillator(amplitude, freq, width, duration, fs);
            % set range for [0, 2]
            app.squareOscillator = app.squareOscillator + 1;
        end

        %{
            Initializes the square oscillator
        %}
        function initializeSawtooth(app, amplitude, freq,  duration, fs)
            
            app.sawtoothOscillator = generateSawtoothOscillator(amplitude, freq, duration, fs);
            % set range for [0, 2]
            app.sawtoothOscillator = app.sawtoothOscillator + 1;
        end

        %{
            Initializes the ADSR envelope
        %}
        function initializeADSR(app, duration, attack, decay, sustain, release, fs)

            app.adsrEnvelope = generateEnvelope(duration, attack, decay, sustain, release, fs);
        end

        %{
            Plays sound of .wav file, from the beginning
        %}
        function playAudio(app)
         
            playSound(app.currWaveFileObj);    
        end

        %{
            Stops playing sound (if currently playing)
        %}
        function stopAudio(app)

            stopSound();   
        end

        %{
            Applies all calculations (in order)
        %}
        function updateSample(app)

            modulateWithOscillators(app);
            applyADSR(app);
            app.currWaveFileObj = changeAmplitude(app.currWaveFileObj, app.waveAmplitude/50);
        end

        %{
            Modulates wave with both oscillators
        %}
        function modulateWithOscillators(app)
            
            app.currWaveFileObj.timeData = modulation(app.originalWaveFileObj.timeData, app.squareOscillator);
            app.currWaveFileObj.timeData = modulation(app.currWaveFileObj.timeData, app.sawtoothOscillator);
        end

        %{
            Applies ADSR to amplitude
        %}
        function applyADSR(app)
            disp(size(app.adsrEnvelope));
            disp(size(app.currWaveFileObj.timeData));
            app.currWaveFileObj.timeData = app.currWaveFileObj.timeData .* app.adsrEnvelope;
        end

        %{
            Plots the two graphs of our data.
        %}
        function loadPlot(app)
            
            timeAxesHandle = app.TimeAxes;
            freqAxesHandle = app.FreqAxes;

            plot(timeAxesHandle, app.currWaveFileObj.timeData);
            ylim(timeAxesHandle, [-1, 1]);
            plot(freqAxesHandle, abs(fft(app.currWaveFileObj.timeData)));
        end

        %{
            Plots oscillator type. Currently only plotting 2 periods

            @param axesHandle - axes reference
            @param audioData - data of audio (time domain)
        %}
        function plotOscillator(app, axesHandle, fs, audioData)
            
            numPeriodsToPlot = 2;
            plot(axesHandle, audioData(1:fs * numPeriodsToPlot / app.oscillatorFrequency)); 
            
            ylim(axesHandle, [-1, 3]);
            yline(axesHandle, 1, 'k--');
        end

        %{
            Modulates wave with both oscillators
        %}
        function plotADSR(app, axesHandle, envelope)

            plot(axesHandle, envelope); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.oscillatorFrequency = 440;
            app.duration = 2;
            app.fs = 44100;
            app.waveAmplitude = 50;

            app.squareWidth = 0;
            app.squareAmplitude = 1;
            app.sawtoothAmplitude = 1;

            initializeSquare(app, app.squareAmplitude, ...
                app.oscillatorFrequency, app.squareWidth, app.duration, app.fs);

            initializeSawtooth(app, app.sawtoothAmplitude/2, ...
                app.oscillatorFrequency, app.duration, app.fs);

            app.adsrAttack = 0.1;
            app.adsrDecay = 0.5;
            app.adsrSustain = 50;
            app.adsrRelease = 0.15;

            initializeADSR(app, app.duration, app.adsrAttack, ...
                app.adsrDecay,app.adsrSustain, app.adsrRelease, app.fs);

            plotOscillator(app, app.SquareOscAxes, app.fs, app.squareOscillator);
            plotOscillator(app, app.SawtoothOscAxes, app.fs, app.sawtoothOscillator);
            plotADSR(app, app.ADSREnvelopeAxes, app.adsrEnvelope);
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed2(app, event)
            % read in data. ref:
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            % https://www.mathworks.com/help/matlab/ref/fullfile.html
            
            [filename, path] = uigetfile('*.wav');
            app.FileNameEditField.Value = filename;
            
            loadWav(app, fullfile(path, filename));
            app.fs = app.originalWaveFileObj.fs;
            app.duration = length(app.originalWaveFileObj.timeData)/app.fs;


            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, app.duration, app.fs); 
            initializeSawtooth(app, app.sawtoothAmplitude/2, app.oscillatorFrequency, app.duration, app.fs);
            

            initializeADSR(app, app.duration, app.adsrAttack, ...
                app.adsrDecay,app.adsrSustain, app.adsrRelease, app.fs);


            updateSample(app);
            % load the plots whenever new file is uploaded
            loadPlot(app);
            plotOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.squareOscillator);
            plotOscillator(app, app.SawtoothOscAxes, app.currWaveFileObj.fs, app.sawtoothOscillator);
            plotADSR(app, app.ADSREnvelopeAxes, app.adsrEnvelope);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            playAudio(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            stopAudio(app);
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged2(app, event)
            app.waveAmplitude = app.VolumeSlider.Value;

            updateSample(app);
            loadPlot(app);
        end

        % Value changed function: SquareOscAmpSlider
        function SquareOscAmpSliderValueChanged(app, event)
            app.squareAmplitude = app.SquareOscAmpSlider.Value / 50;

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, app.duration, app.fs);
            
            updateSample(app);
            plotOscillator(app, app.SquareOscAxes, app.fs, app.squareOscillator);
            loadPlot(app);
        end

        % Value changed function: SquareOscWidthSlider
        function SquareOscWidthSliderValueChanged(app, event)
            app.squareWidth = app.SquareOscWidthSlider.Value;

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, app.duration, app.fs);
            
            updateSample(app);
            plotOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.squareOscillator);
            loadPlot(app);
        end

        % Value changed function: SawtoothAmpSlider
        function SawtoothAmpSliderValueChanged(app, event)
            app.sawtoothAmplitude = app.SawtoothAmpSlider.Value/(2 * 50);
            
            initializeSawtooth(app, app.sawtoothAmplitude, app.oscillatorFrequency, app.duration, app.fs); 
            
            updateSample(app);
            plotOscillator(app, app.SawtoothOscAxes, app.fs, app.sawtoothOscillator);
            loadPlot(app);
        end

        % Value changed function: OscillatorFrequencyEditField
        function OscillatorFrequencyEditFieldValueChanged(app, event)
            app.oscillatorFrequency = str2double(app.OscillatorFrequencyEditField.Value);

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, app.duration, app.fs); 
            initializeSawtooth(app, app.sawtoothAmplitude/2, app.oscillatorFrequency, app.duration, app.fs);
            
            updateSample(app);
            plotOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.squareOscillator);
            loadPlot(app);
        end

        % Value changed function: AttackSlider
        function AttackSliderValueChanged(app, event)
            app.adsrAttack = app.AttackSlider.Value;

            initializeADSR(app, app.duration, app.adsrAttack, ...
                app.adsrDecay,app.adsrSustain, app.adsrRelease, app.fs);
            updateSample(app);
            loadPlot(app);
            plotADSR(app, app.ADSREnvelopeAxes, app.adsrEnvelope);
        end

        % Value changed function: DecaySlider
        function DecaySliderValueChanged(app, event)
            app.adsrDecay = app.DecaySlider.Value;
            
            initializeADSR(app, app.duration, app.adsrAttack, ...
                app.adsrDecay,app.adsrSustain, app.adsrRelease, app.fs);
            updateSample(app);
            loadPlot(app);
            plotADSR(app, app.ADSREnvelopeAxes, app.adsrEnvelope);
        end

        % Value changed function: SustainSlider
        function SustainSliderValueChanged(app, event)
            app.adsrSustain = app.SustainSlider.Value;
            
            initializeADSR(app, app.duration, app.adsrAttack, ...
                app.adsrDecay,app.adsrSustain, app.adsrRelease, app.fs);
            updateSample(app);
            loadPlot(app);
            plotADSR(app, app.ADSREnvelopeAxes, app.adsrEnvelope);
        end

        % Value changed function: ReleasesSlider
        function ReleasesSliderValueChanged(app, event)
            app.adsrRelease = app.ReleasesSlider.Value;

            initializeADSR(app, app.duration, app.adsrAttack, ...
                app.adsrDecay,app.adsrSustain, app.adsrRelease, app.fs);
            updateSample(app);
            loadPlot(app);
            plotADSR(app, app.ADSREnvelopeAxes, app.adsrEnvelope);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {672, 672, 672};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {672, 672};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {258, '1x', 276};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SquareOscAxes = uiaxes(app.GridLayout2);
            title(app.SquareOscAxes, 'Square Oscillator (2 Periods)')
            app.SquareOscAxes.XTick = [];
            app.SquareOscAxes.YTick = [];
            app.SawtoothOscAxes = uiaxes(app.GridLayout2);
            title(app.SawtoothOscAxes, 'Sawtooth Oscillator (2 Periods)')
            app.SawtoothOscAxes.XTick = [];
            app.SawtoothOscAxes.YTick = [];
            app.LFOAmountSliderLabel = uilabel(app.GridLayout2);
            app.LFOAmountSliderLabel.Text = 'LFO Amount';
            app.LFOAmountSlider = uislider(app.GridLayout2);
            app.LFOAmountSlider.Value = 50;
            app.EnvelopeAmountSliderLabel = uilabel(app.GridLayout2);
            app.EnvelopeAmountSliderLabel.Text = 'Envelope Amount';
            app.EnvelopeAmountSlider = uislider(app.GridLayout2);
            app.EnvelopeAmountSlider.Value = 50;
            app.AmplitudeSliderLabel = uilabel(app.GridLayout2);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.SquareOscAmpSlider = uislider(app.GridLayout2);
            app.SquareOscAmpSlider.ValueChangedFcn = createCallbackFcn(app, @SquareOscAmpSliderValueChanged, true);
            app.SquareOscAmpSlider.Value = 50;
            app.WidthSliderLabel = uilabel(app.GridLayout2);
            app.WidthSliderLabel.Text = 'Width';
            app.SquareOscWidthSlider = uislider(app.GridLayout2);
            app.SquareOscWidthSlider.Limits = [-100 100];
            app.SquareOscWidthSlider.MajorTicks = [-100 -50 0 50 100];
            app.SquareOscWidthSlider.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.SquareOscWidthSlider.ValueChangedFcn = createCallbackFcn(app, @SquareOscWidthSliderValueChanged, true);
            app.SquareOscWidthSlider.MinorTicks = [];
            app.TuneSliderLabel = uilabel(app.GridLayout2);
            app.TuneSliderLabel.Text = 'Tune';
            app.TuneSlider = uislider(app.GridLayout2);
            app.TuneSlider.Value = 50;
            app.AmplitudeSlider_3Label = uilabel(app.GridLayout2);
            app.AmplitudeSlider_3Label.Text = 'Amplitude';
            app.SawtoothAmpSlider = uislider(app.GridLayout2);
            app.SawtoothAmpSlider.ValueChangedFcn = createCallbackFcn(app, @SawtoothAmpSliderValueChanged, true);
            app.SawtoothAmpSlider.Value = 50;
            app.EnterFrequencyForOscillatorEditFieldLabel = uilabel(app.GridLayout2);
            app.EnterFrequencyForOscillatorEditFieldLabel.Text = 'Enter Frequency For Oscillator';
            app.OscillatorFrequencyEditField = uieditfield(app.GridLayout2, 'text');
            app.OscillatorFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @OscillatorFrequencyEditFieldValueChanged, true);
            app.OscillatorFrequencyEditField.Value = '440';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.FreqAxes = uiaxes(app.GridLayout3);
            title(app.FreqAxes, 'Frequency Waveform')
            xlabel(app.FreqAxes, 'Angular Frequency')
            ylabel(app.FreqAxes, 'Amplitude')
            zlabel(app.FreqAxes, 'Z')
            app.FreqAxes.XTick = [];
            app.FreqAxes.XTickLabel = '';
            app.FreqAxes.YTick = [];
            app.FreqAxes.YTickLabel = '';
            app.FreqAxes.ZTick = [];
            app.TimeAxes = uiaxes(app.GridLayout3);
            title(app.TimeAxes, 'Audio Waveform')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Amplitude')
            zlabel(app.TimeAxes, 'Z')
            app.TimeAxes.XTick = [];
            app.TimeAxes.XTickLabel = '';
            app.TimeAxes.YTick = [];
            app.TimeAxes.YTickLabel = '';
            app.TimeAxes.ZTick = [];
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '■';
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '►';
            app.MediaControlLabel = uilabel(app.GridLayout3);
            app.MediaControlLabel.Text = 'Media Control';
            app.ImportFileButton = uibutton(app.GridLayout3, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed2, true);
            app.ImportFileButton.Text = 'Import File';
            app.FileNameEditField = uieditfield(app.GridLayout3, 'text');
            app.FileNameEditField.Value = 'File Name';
            app.VolumeSliderLabel = uilabel(app.GridLayout3);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.GridLayout3);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged2, true);
            app.VolumeSlider.Value = 50;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout1 = uigridlayout(app.RightPanel);
            app.ADSREnvelopeAxes = uiaxes(app.GridLayout1);
            title(app.ADSREnvelopeAxes, 'ADSR Envelope')
            app.ADSREnvelopeAxes.XTick = [];
            app.ADSREnvelopeAxes.YTick = [];
            app.SustainSliderLabel = uilabel(app.GridLayout1);
            app.SustainSliderLabel.Text = 'Sustain (%)';
            app.ReleasesSliderLabel = uilabel(app.GridLayout1);
            app.ReleasesSliderLabel.Text = 'Release (s)';
            app.ReleasesSlider = uislider(app.GridLayout1);
            app.ReleasesSlider.Limits = [0 5];
            app.ReleasesSlider.ValueChangedFcn = createCallbackFcn(app, @ReleasesSliderValueChanged, true);
            app.ReleasesSlider.MinorTicks = [0.5 1.5 2.5 3.5 4.5];
            app.ReleasesSlider.Value = 1.5;
            app.SustainSlider = uislider(app.GridLayout1);
            app.SustainSlider.ValueChangedFcn = createCallbackFcn(app, @SustainSliderValueChanged, true);
            app.SustainSlider.MinorTicks = [10 30 50 70 90];
            app.SustainSlider.Value = 50;
            app.AttacksLabel = uilabel(app.GridLayout1);
            app.AttacksLabel.Text = 'Attack (s)';
            app.DecaysLabel = uilabel(app.GridLayout1);
            app.DecaysLabel.Text = 'Decay (s)';
            app.DecaySlider = uislider(app.GridLayout1);
            app.DecaySlider.Limits = [0 5];
            app.DecaySlider.MajorTicks = [0 1 2 3 4 5];
            app.DecaySlider.ValueChangedFcn = createCallbackFcn(app, @DecaySliderValueChanged, true);
            app.DecaySlider.MinorTicks = [0.5 1.5 2.5 3.5 4.5];
            app.DecaySlider.Value = 0.5;
            app.AttackSlider = uislider(app.GridLayout1);
            app.AttackSlider.Limits = [0 5];
            app.AttackSlider.MajorTicks = [0 1 2 3 4 5];
            app.AttackSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.AttackSlider.ValueChangedFcn = createCallbackFcn(app, @AttackSliderValueChanged, true);
            app.AttackSlider.MinorTicks = [0.5 1.5 2.5 3.5 4.5];
            app.AttackSlider.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SYNTH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end